classdef particleSimulationParticipant < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        PlayPauseButton                 matlab.ui.control.StateButton
        ScenarioDropDown                matlab.ui.control.DropDown
        ScenarioDropDownLabel           matlab.ui.control.Label
        ParticleMasskgEditField         matlab.ui.control.NumericEditField
        ParticleMasskgEditFieldLabel    matlab.ui.control.Label
        FluidViscocityEditField         matlab.ui.control.NumericEditField
        FluidViscocityEditFieldLabel    matlab.ui.control.Label
        MotionFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        MotionFrictionCoefficientLabel  matlab.ui.control.Label
        StaticFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        StaticFrictionCoefficientEditFieldLabel  matlab.ui.control.Label
        Z2mAGauge                       matlab.ui.control.LinearGauge
        Z2mAGaugeLabel                  matlab.ui.control.Label
        Z1mAGauge                       matlab.ui.control.LinearGauge
        Z1mAGaugeLabel                  matlab.ui.control.Label
        Y2mAGauge                       matlab.ui.control.LinearGauge
        Y2mAGaugeLabel                  matlab.ui.control.Label
        Y1mAGauge                       matlab.ui.control.LinearGauge
        Y1mAGaugeLabel                  matlab.ui.control.Label
        X2mAGauge                       matlab.ui.control.LinearGauge
        X2mAGaugeLabel                  matlab.ui.control.Label
        X1mAGauge                       matlab.ui.control.LinearGauge
        X1mAGaugeLabel                  matlab.ui.control.Label
        ParticleDiametermEditField      matlab.ui.control.NumericEditField
        ParticleDiametermEditFieldLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        numParticles
        particleFunctions
        simulationTimerProperty
        drawTimerProperty        
        particleArrayPreviousLocation
        particleArrayBaseVelocity
        particleArrayBaseVelocityStartTime
        particleArrayPreviousDragForce
        particleArrayPreviousAcceleration
        particleArrayLocation
        particleArrayVelocity
        particleArrayForce
        lastUpdate
        fileID
        polygon
        polyLine
        endLine;
        particlePoints
        loopComplete
        tMax
        timestep
        timePassed
        fd
        mesh
        haltParticlesInEndZone
        startTime
        currentlyDoingWorkSemaphore
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.numParticles = 3;
            %general simulation timer
            app.simulationTimerProperty = timer('Period',0.01,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)timerCallback(app));
         %   app.drawTimerProperty = timer('Period',0.5,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)drawTimerCallback(app));
            app.particleFunctions = ParticleFunctions(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));           
            app.lastUpdate = clock;
            app.fileID = fopen('results.csv','w');
            disp(pwd + "\n");
            fprintf(app.fileID,'%s\n',pwd);
            app.loopComplete = true;            
            app.particlePoints = plot(app.UIAxes,0,0);
            app.polygon = Polygons(app.UIAxes.XLim(2));
            app.tMax = 1;
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);          
            app.particleArrayVelocity = zeros(app.numParticles, 2);
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayBaseVelocityStartTime = clock;
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            hold (app.UIAxes, 'on');
            app.polyLine = plot(app.UIAxes,app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2), 'Color','b');
            app.endLine = plot(app.UIAxes,app.polygon.currentEndZone(:,1),app.polygon.currentEndZone(:,2), 'Color','g');
            app.fd = FlowData();
            app.fd.FlowValues = app.fd.FlowValues.* 0.001; %Change to centre velocity 1 mm/s. Multiply to alter from here.
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
            app.currentlyDoingWorkSemaphore = false;
            app.timePassed = 0;
            app.timestep = 0;
            
            app.X1mAGauge.Value = 2.25;
            
            if(true) %Set to true if needed.
                app.polygon.change(2);
                app.particleFunctions.writeMeshToFile(app.polygon)
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'q'
                        app.X1mAGauge.Value = app.X1mAGauge.Value + 0.05;
                case 'w'
                        app.X1mAGauge.Value = app.X1mAGauge.Value - 0.05;
                case 'e'
                        app.X2mAGauge.Value = app.X2mAGauge.Value + 0.05;
                case 'r'
                        app.X2mAGauge.Value = app.X2mAGauge.Value - 0.05;
                case 'u'
                        app.Y1mAGauge.Value = app.Y1mAGauge.Value + 0.05;
                case 'i'
                        app.Y1mAGauge.Value = app.Y1mAGauge.Value - 0.05;
                case 'o'
                        app.Y2mAGauge.Value = app.Y2mAGauge.Value + 0.05;
                case 'p'
                        app.Y2mAGauge.Value = app.Y2mAGauge.Value - 0.05;
                case 'y'
                    app.X1mAGauge.Value = 0;
                    app.X2mAGauge.Value = 0;
                    app.Y1mAGauge.Value = 0;
                    app.Y1mAGauge.Value = 0;
                    app.Z1mAGauge.Value = 0;
                    app.Z2mAGauge.Value = 0;
                    close all;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.simulationTimerProperty)
          %  stop(app.drawTimerProperty)
            fclose(app.fileID);
            close all;
            delete(app)
            disp("App Stopped")
        end

        % Callback function
        function XCoilsSwitchValueChanged(app, event)
            if(app.XCoilsSwitch.Value == '0')
                app.X1mAGauge.Value = 0;
                app.X2mAGauge.Value = 0;
            end
        end

        % Callback function
        function YCoilsSwitchValueChanged(app, event)
            if(app.YCoilsSwitch.Value == '0')
                app.Y1mAGauge.Value = 0;
                app.Y2mAGauge.Value = 0;
            end            
        end

        % Value changed function: ParticleDiametermEditField
        function ParticleDiametermEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: StaticFrictionCoefficientEditField
        function StaticFrictionCoefficientEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: MotionFrictionCoefficientEditField
        function MotionFrictionCoefficientEditFieldValueChanged(app, event)
            app.UIAxes.XLim(2)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: FluidViscocityEditField
        function FluidViscocityEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ParticleMasskgEditField
        function ParticleMasskgEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ScenarioDropDown
        function ScenarioDropDownValueChanged(app, event)
            app.PlayPauseButton.Value = 0;
            app.PlayPauseButtonValueChanged([]);
            value = app.ScenarioDropDown.Value;
            if(value == "Experiment 1")
                app.polygon = app.polygon.change(2);
            else 
                if(value == "Experiment 2")
                    app.polygon = app.polygon.change(3);
                else 
                    if(value == "Experiment 3")
                        app.polygon = app.polygon.change(4);
                    else
                        app.polygon = app.polygon.change(1);
                    end
                end
            end
            delete(app.polyLine);
            delete(app.endLine);
            hold (app.UIAxes, 'on');            
            app.polyLine = plot(app.UIAxes,app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2), 'Color','b');
            app.endLine = plot(app.UIAxes,app.polygon.currentEndZone(:,1),app.polygon.currentEndZone(:,2), 'Color','g');
            %reset particle position & velocity to start area
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, length(app.particleArrayLocation));
            app.particleArrayVelocity = zeros(length(app.particleArrayLocation), 2);
            app.particleArrayForce = zeros(length(app.particleArrayLocation), 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayBaseVelocityStartTime = clock;
            app.particleArrayPreviousDragForce = zeros(length(app.particleArrayLocation), 2);
            app.particleArrayPreviousAcceleration = zeros(length(app.particleArrayLocation), 2);
            
        end

        % Value changed function: PlayPauseButton
        function PlayPauseButtonValueChanged(app, event)
            value = app.PlayPauseButton.Value;
            if(value)
                app.startTime = clock;
                %app.startTime = posixtime(datetime(datestr(now)));
                set(app.PlayPauseButton,'BackgroundColor',[0.7,1,0.7]);
                app.lastUpdate = clock;
                start(app.simulationTimerProperty);
              %  start(app.drawTimerProperty);
            else
                set(app.PlayPauseButton,'BackgroundColor',[1,0.7,0.7]);
                stop(app.simulationTimerProperty);
             %   stop(app.drawTimerProperty);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {727, 727};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {165, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ParticleDiametermEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleDiametermEditFieldLabel.Enable = 'off';
            app.ParticleDiametermEditFieldLabel.Text = 'Particle Diameter (m)';
            app.ParticleDiametermEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleDiametermEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleDiametermEditFieldValueChanged, true);
            app.ParticleDiametermEditField.Enable = 'off';
            app.ParticleDiametermEditField.Value = 5.6e-06;
            app.X1mAGaugeLabel = uilabel(app.LeftPanel);
            app.X1mAGaugeLabel.Text = 'X1 (mA)';
            app.X1mAGauge = uigauge(app.LeftPanel, 'linear');
            app.X1mAGauge.Limits = [-1 1];
            app.X2mAGaugeLabel = uilabel(app.LeftPanel);
            app.X2mAGaugeLabel.Text = 'X2 (mA)';
            app.X2mAGauge = uigauge(app.LeftPanel, 'linear');
            app.X2mAGauge.Limits = [-1 1];
            app.Y1mAGaugeLabel = uilabel(app.LeftPanel);
            app.Y1mAGaugeLabel.Text = 'Y1 (mA)';
            app.Y1mAGauge = uigauge(app.LeftPanel, 'linear');
            app.Y1mAGauge.Limits = [-1 1];
            app.Y2mAGaugeLabel = uilabel(app.LeftPanel);
            app.Y2mAGaugeLabel.Text = 'Y2 (mA)';
            app.Y2mAGauge = uigauge(app.LeftPanel, 'linear');
            app.Y2mAGauge.Limits = [-1 1];
            app.Z1mAGaugeLabel = uilabel(app.LeftPanel);
            app.Z1mAGaugeLabel.Enable = 'off';
            app.Z1mAGaugeLabel.Text = 'Z1 (mA)';
            app.Z1mAGauge = uigauge(app.LeftPanel, 'linear');
            app.Z1mAGauge.Limits = [-1 1];
            app.Z1mAGauge.Enable = 'off';
            app.Z2mAGaugeLabel = uilabel(app.LeftPanel);
            app.Z2mAGaugeLabel.Enable = 'off';
            app.Z2mAGaugeLabel.Text = 'Z2 (mA)';
            app.Z2mAGauge = uigauge(app.LeftPanel, 'linear');
            app.Z2mAGauge.Limits = [-1 1];
            app.Z2mAGauge.Enable = 'off';
            app.StaticFrictionCoefficientEditFieldLabel = uilabel(app.LeftPanel);
            app.StaticFrictionCoefficientEditFieldLabel.Enable = 'off';
            app.StaticFrictionCoefficientEditFieldLabel.Text = {'(Static) Friction Coefficient'; ''};
            app.StaticFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StaticFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @StaticFrictionCoefficientEditFieldValueChanged, true);
            app.StaticFrictionCoefficientEditField.Enable = 'off';
            app.StaticFrictionCoefficientEditField.Value = 0.000135;
            app.MotionFrictionCoefficientLabel = uilabel(app.LeftPanel);
            app.MotionFrictionCoefficientLabel.Enable = 'off';
            app.MotionFrictionCoefficientLabel.Text = {'(Motion) Friction Coefficient'; ''};
            app.MotionFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MotionFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @MotionFrictionCoefficientEditFieldValueChanged, true);
            app.MotionFrictionCoefficientEditField.Enable = 'off';
            app.MotionFrictionCoefficientEditField.Value = 3e-05;
            app.FluidViscocityEditFieldLabel = uilabel(app.LeftPanel);
            app.FluidViscocityEditFieldLabel.Enable = 'off';
            app.FluidViscocityEditFieldLabel.Text = 'Fluid Viscocity';
            app.FluidViscocityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FluidViscocityEditField.ValueChangedFcn = createCallbackFcn(app, @FluidViscocityEditFieldValueChanged, true);
            app.FluidViscocityEditField.Enable = 'off';
            app.FluidViscocityEditField.Value = 0.004;
            app.ParticleMasskgEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleMasskgEditFieldLabel.Enable = 'off';
            app.ParticleMasskgEditFieldLabel.Text = 'Particle Mass (kg)';
            app.ParticleMasskgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleMasskgEditFieldValueChanged, true);
            app.ParticleMasskgEditField.Enable = 'off';
            app.ParticleMasskgEditField.Value = 2.069e-13;
            app.ScenarioDropDownLabel = uilabel(app.LeftPanel);
            app.ScenarioDropDownLabel.Text = {'Scenario'; ''};
            app.ScenarioDropDown = uidropdown(app.LeftPanel);
            app.ScenarioDropDown.Items = {'Training', 'Experiment 1', 'Experiment 2', 'Experiment 3'};
            app.ScenarioDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenarioDropDownValueChanged, true);
            app.ScenarioDropDown.Tooltip = {'Select which scenario to use. The experiment leader will tell you which Scenario to use.'};
            app.ScenarioDropDown.Value = 'Training';
            app.PlayPauseButton = uibutton(app.LeftPanel, 'state');
            app.PlayPauseButton.ValueChangedFcn = createCallbackFcn(app, @PlayPauseButtonValueChanged, true);
            app.PlayPauseButton.Tooltip = {'Play Pause Button'; 'Use to halt and restart the simulator'};
            app.PlayPauseButton.Text = 'Play / Pause';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Workspace')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z (m)')
            app.UIAxes.XLim = [-0.00096 0.00096];
            app.UIAxes.YLim = [-0.00096 0.00096];
            app.UIAxes.ZLim = [-0.00096 0.00096];
            app.UIAxes.Tag = 'particleWorskace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = particleSimulationParticipant

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end