classdef particleSimulationTrainModeFFPlayFirstHomePC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        StartTrainingButton             matlab.ui.control.Button
        ScenarioEditField               matlab.ui.control.EditField
        ScenarioEditFieldLabel          matlab.ui.control.Label
        CentreMassViewCheckBox          matlab.ui.control.CheckBox
        ButtonGroup                     matlab.ui.container.ButtonGroup
        ControllerButton                matlab.ui.control.RadioButton
        MouseButton                     matlab.ui.control.RadioButton
        KeyboardButton                  matlab.ui.control.RadioButton
        NumberofParticlesEditField      matlab.ui.control.NumericEditField
        NumberofParticlesEditFieldLabel  matlab.ui.control.Label
        FluidFlowmsEditField            matlab.ui.control.NumericEditField
        FluidFlowmsEditFieldLabel       matlab.ui.control.Label
        MagForceRestrictMAM2EditField   matlab.ui.control.NumericEditField
        MagForceRestrictMAM2EditFieldLabel  matlab.ui.control.Label
        ScenarioDropDown                matlab.ui.control.DropDown
        ScenarioDropDownLabel           matlab.ui.control.Label
        ParticleMasskgEditField         matlab.ui.control.NumericEditField
        ParticleMasskgEditFieldLabel    matlab.ui.control.Label
        FluidViscocityEditField         matlab.ui.control.NumericEditField
        FluidViscocityEditFieldLabel    matlab.ui.control.Label
        MotionFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        MotionFrictionCoefficientLabel  matlab.ui.control.Label
        StaticFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        StaticFrictionCoefficientEditFieldLabel  matlab.ui.control.Label
        Y1MAGauge                       matlab.ui.control.LinearGauge
        Y1MAGaugeLabel                  matlab.ui.control.Label
        X1MAGauge                       matlab.ui.control.LinearGauge
        X1MAGaugeLabel                  matlab.ui.control.Label
        ParticleDiametermEditField      matlab.ui.control.NumericEditField
        ParticleDiametermEditFieldLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        PercentageinGoalEditField       matlab.ui.control.NumericEditField
        TimeremainingsLabel             matlab.ui.control.Label
        TimeRemainingsEditField         matlab.ui.control.NumericEditField
        TimeRemainingsEditFieldLabel    matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        simulationTimerProperty
        drawTimerProperty
        hapticFeedbackIdleProperty
        numParticles
        particleFunctions
        simTimerPeriod
        previousMagforce
        particleArrayPreviousLocation
        particleArrayBaseVelocity
        particleArrayBaseVelocityStartTime
        particleArrayPreviousDragForce
        particleArrayPreviousAcceleration
        particleArrayLocation
        particleArrayVelocity
        particleArrayForce
        lastUpdate
        fileID
        polygon
        polyLine
        endLine
        goalIndex
        wrongEndLine
        particlePoints
        loopComplete
        tMax
        timestep
        timePassed
        timeLag
        fd
        mesh
        haltParticlesInEndZone
        bouncedLastLoop
        bouncedVisualLastLoop
        startTime
        currentlyDoingWorkSemaphore
        joyStick
        controlMethod
        mousePosition
        magLine
        timeLimit
        slowDown
        printCounter
        testNumber
        rotation
        hapticFeedback
        testOrder
        maxForce
        QuestionaireFID
        DataStoreLocation
        dataFileNameList
        writeToFile
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           rng(1000,"twister");
            app.numParticles = app.NumberofParticlesEditField.Value;
            app.simTimerPeriod = 0.05; %0.05 %Draw was 0.3
            app.previousMagforce = 0;
            app.slowDown = 1;
            app.maxForce = 2.25;%1.6;
            app.printCounter = 0;
%              fourPolygonLocation = "E:\SwarmSimulation\COMSOLFLOWDATA\4bifpointsSimONLY.csv";
%             fourMeshValuesLocation = "E:\SwarmSimulation\COMSOLFLOWDATA\BenResult0_02.csv";
% %             fourPolygonLocation = "C:\Users\bj20907\Documents\GitHub\SwarmSimulation\COMSOLFLOWDATA\4bifpointsSimONLY.csv";
% %             fourMeshValuesLocation = "C:\Users\bj20907\Documents\GitHub\SwarmSimulation\COMSOLFLOWDATA\BenResult0_01.csv";
%             [locX,locY,flowX,flowY] = ReadInFlowDataFromComsolCSV(fourPolygonLocation, fourMeshValuesLocation);
%             saveME = [locX;locY]';
%             writematrix(saveME,'M.csv') 
        %   app.fd = FlowData([locX; locY]', [flowX; flowY]');
            app.fd = FlowData05();
            %general simulation timer
          %  app.simulationTimerProperty = timer('Period',app.simTimerPeriod,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)timerCallback(app));
          %  app.drawTimerProperty = timer('Period',0.3,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)drawTimerCallback(app));
          %  app.hapticFeedbackIdleProperty = timer('Period',app.simTimerPeriod,'TasksToExecute',inf,"ExecutionMode",'fixedRate',"TimerFcn", @(obj, event)idleHapticCallback(app));
            app.particleFunctions = ParticleFunctions(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));           
            app.lastUpdate = clock;
            newFileName = "Training1" + "_" + app.lastUpdate(4) + "_" + app.lastUpdate(5) + "_results.csv";
            app.fileID = fopen(newFileName,'w');
            %app.fileID = fopen('results.csv','w');
            disp(pwd + "\n");
            fprintf('%s\n',pwd);
            app.loopComplete = true;            
            app.particlePoints = plot(app.UIAxes,0,0);
            app.polygon = Polygons(app.UIAxes.XLim(2), app.fd);
            app.tMax = 1;
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);          
            app.particleArrayVelocity = zeros(app.numParticles, 2);
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayBaseVelocityStartTime = clock;
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            app.bouncedLastLoop = zeros(app.numParticles,1);
            app.bouncedVisualLastLoop = zeros(app.numParticles,1);
            hold(app.UIAxes, 'on');
            app.goalIndex = 1;
            app.polyLine = plot(app.UIAxes,app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2), 'Color','b');
            for(lineCount = 1:length(app.polygon.currentEndZone)-1)
                app.wrongEndLine(lineCount) = plot(app.UIAxes,app.polygon.currentEndZone(lineCount,:,1),app.polygon.currentEndZone(lineCount,:,2), 'Color','r');
            end
            app.endLine = plot(app.UIAxes,app.polygon.currentEndZone(app.goalIndex,:,1),app.polygon.currentEndZone(app.goalIndex,:,2), 'Color','g');
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
            app.currentlyDoingWorkSemaphore = false;
            app.timePassed = 0;
            app.timestep = 0;
            app.timeLag = 0;
            app.joyStick = 0;
            app.controlMethod = "TrainingModel";
            InitHaptic();
            app.mousePosition = [0 0];
            app.magLine = plot(app.UIAxes,0,0);
            app.timeLimit = 1;%120;
            app.testNumber = 0;
            app.rotation = 0;
            app.hapticFeedback = [0,0,0];
            %50 tests
            app.testOrder = 1:120;
%             Uncomment for random order!
%             app.testOrder = app.testOrder(randperm(length(app.testOrder)));
            app.testOrder = [app.testOrder,length(app.testOrder)+1];
            OrderFileID = fopen("TestOrder_" + app.lastUpdate(4) + "_" + app.lastUpdate(5)+".csv",'w');
            fprintf(OrderFileID,mat2str(app.testOrder));
            fclose(OrderFileID);
          %  app.DataStoreLocation = "D:\EssexDocuments\EXMAR23\unrandomised\CombinedSHORT";
            app.DataStoreLocation = "D:\EssexDocuments\EXMAR23\TESTDATACLUMP56";
       %     app.DataStoreLocation = "C:\Users\bj20907\Downloads\SpoofData";
            % app.DataStoreLocation = "";
            %app.dataFileNameList = {"D:\EssexDocuments\EXMAR23\TESTDATA\1\Test0012_11_3_results.csv"};
            app.dataFileNameList = [];
            app.writeToFile = false;

%             tr = triangulation(polyshape(app.polygon.currentPoly(:,1),app.polygon.currentPoly(:,2)));
%             model = createpde(1);
%             tnodes = tr.Points';
%             telements = tr.ConnectivityList';
%             model.geometryFromMesh(tnodes, telements);
%             app.mesh = generateMesh(model, 'Hmax', 0.001);%was 0.000073 for old one.
    %        app.mesh =  alphaShape(app.fd.FlowLocations{1});
            app.mesh = delaunayTriangulation(app.fd.FlowLocations{1});
            if(false) %Set to true if needed.
                app.polygon = app.polygon.change(2);
                app.particleFunctions.writeMeshToFile(app.polygon)
            end
%             prompt = {'Age','Gender','Corrected Eyesight','Right/Left Handed'};
%             dlgtitle = 'Statistical questions';
%             answer = inputdlg(prompt,dlgtitle);
%             app.QuestionaireFID = fopen("Questionaire" + app.lastUpdate(4) + "_" + app.lastUpdate(5)+".csv",'w');
%             fprintf(app.QuestionaireFID,strcat('\r\n Age,',answer{1}));
%             fprintf(app.QuestionaireFID,strcat('\r\n Gender,',answer{2}));
%             fprintf(app.QuestionaireFID,strcat('\r\n Corrected Eyesight,',answer{3}));
%             fprintf(app.QuestionaireFID,strcat('\r\n Right/Left Handed,',answer{4}));
            %fclose(app.QuestionaireFID);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            value = app.MagForceRestrictMAM2EditField.Value;
            if((key == 'space') & (app.StartTrainingButton.Enable == 'on'))
                 set(app.StartTrainingButton,'Text',"Running...");
                app.startTime = clock;
                set(app.StartTrainingButton,'BackgroundColor',[0.7,1,0.7]);
                app.lastUpdate = clock;
                app.StartTrainingButton.Enable = false;
    
                startZone = 1;
                n_particles = 500;

                %IRL First Test
                %Environment Setup
                if(app.testNumber == 0)
                    resetSimForMLTraining(app,1/6,startZone,n_particles);
                end
                terminate(pyenv)
                try
                    pyenv("Version","C:\Users\beno1\AppData\Local\Programs\Python\Python310\python.exe",ExecutionMode="OutOfProcess");
                catch
                    try
                        pyenv("Version","C:\Users\bj20907\AppData\Local\Programs\Python\Python310\python.exe",ExecutionMode="OutOfProcess");
                        %app.DataStoreLocation = "";
                    catch
                        pyenv("Version","C:\Users\Rumi\AppData\Local\Programs\Python\Python310\python.exe",ExecutionMode="OutOfProcess");
                    end
                end
                if count(py.sys.path,'') == 0
                    insert(py.sys.path,int32(0),'');
                end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %Plotting:
                % plot(squeeze(summed_reward_histories(:,:,1))') - 1st flow value, over all steps
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
                DRAWTRAIN = false;
                DRAWPLAY = true;
                
                n_states = 8;
                n_actions = 2; %Use regression instead here 
                %h_Layers = [80,160];%[164,150];
                %h_Layers = [200,400];
            %    h_Layers = [6,6];
                h_Layers = [10,1];
                % e_psilon = 0.05;
                % l_rate = 0.01;
                Gamma = 0.8;
                flow_rate = 3/6;
                totalLoops = 10;
                trainingLoops = 1;%SET THIS TO 1 AT MINIMUM
                playingLoops=5;

                goalCount = 0;

                summed_reward_histories = [];
                stepIndex = 0;
                hDistToPrint = [];
                loss_history_val = [];
                acc_history_val = [];
                loss_history = [];
                acc_history = [];
                lhDrawn = [];
                accDrawn = [];
                lhvDrawn = [];
                accvDrawn = [];
                rewDrawn = [];

                bestPerformance = realmin;
                buffer = 80;
                goalLocation = [0.010644161 -0.002909076]; %Use this for distance to goal calculations
    
                pyrun("from pythonNNContstructor import *")
               % pyrun("obj1 = QLearning(py_n_states, py_n_actions)", py_n_states = n_states, py_n_actions = n_actions)
                pyrun("random.seed(1000)");
                %currentExpertPolicy = [0.51,0.05,0.1,0.1];%test this
                %[currentExpertStates, currentExpertActions,flow_rate] = loadNewPolicy(app,n_states,Gamma,goalLocation);
                minx = -0.012;
                maxx = 0.012;
                miny = -0.012;
                maxy = 0.012;
                binCount =240;
                %rewardSpace = loadNewRewardFromPolicy(app,minx,maxx,miny,maxy,binCount,binCount);
                %rewardSpace = medfilt1(rewardSpace,5);

                %for(traceStepCount = [1:3])
                for(traceStepCount = 1:8)
                    %traceStepCount = 2;
                    [basic_stateList, actionList, basic_val_stateList, val_actionList] = loadStateActionValPairsFromPolicy(app,0.2,2);%traceStepCount);
                    basic_stateList = statePairsToCorrectState(app,basic_stateList);
                    basic_val_stateList = statePairsToCorrectState(app,basic_val_stateList);
                    
                    h_Layers = [4+traceStepCount,1];

                    % mm = max(max(rewardSpace));
                    % rewardSpace = rewardSpace./max(max(rewardSpace));
                 %   mesh(rewardSpace)
                    % app.FluidFlowmsEditField.Value = flow_rate;
                    % flow_rate = flow_rate./6;
                    %pyrun("irl = IRLClass(numStates,numActions,hiddenLayers,expertFE,epsilon,lr)",numStates=n_states,numActions=n_actions,hiddenLayers=h_Layers, expertFE=currentExpertPolicy,epsilon = e_psilon, lr = l_rate);
                    pyrun("model = NNClass(numStates,numActions,hiddenLayers)",numStates=n_states,numActions=n_actions,hiddenLayers=h_Layers);
                    %pyrun("rlTraining = IRLClass(numStates,numActions,hiddenLayers,epsilon,lr)",numStates=n_states,numActions=n_actions,hiddenLayers=h_Layers,epsilon = e_psilon, lr = l_rate);
        
                    pyrun("model.model.summary()")
        %            pyrun("rlTraining.model.summary()")
        
                    
                    %while(stepIndex < totalSteps)
                   % while(size(loss_history,2)<3)
                   for(totalLoopIndex = 1:totalLoops)

                        rot = 0;
                        %xShiftTrain = rand()-0.5;
                        %yShiftTrain = rand()-0.5;
                        xShiftTrain = 0;
                        yShiftTrain = 0;
                        
                        stateList = translateRotateTrainingData(app, rot, xShiftTrain, yShiftTrain, basic_stateList);
                        val_stateList = translateRotateTrainingData(app, rot, xShiftTrain, yShiftTrain, basic_val_stateList);

                        resetCount = 0;
                        % currentExpertPolicy
                      %  try
                            %FirstLayerW = pyrun("FirstLayerWeights = irl.Optimise(expertPolicy)","FirstLayerWeights",expertPolicy = currentExpertPolicy);
                            %UpdatePolicyList here
                      %  catch
                      %      stepIndex = 9;
                      %      FirstLayerW = zeros(1,n_states);
                      %  end
        
                        if(startZone < 5 && goalCount>25)
                            startZone = startZone + 1;
                            goalCount = 0;
                        end
                        
                        %'Play'
                        "Playing Commencing"
                        t=0;
                        flowNum = [1/6, 1/3, 0.5, 2/3, 5/6, 1];
                        %flowNum = [1/6, 2/6, 0.5]; %1 and 5 weren't training well, try later?
                        % flow_rate = flowNum(randi(length(flowNum),1));
                        %flow_rate = 1;%5/6;
                        distanceCount = 0;
                        rewardSum = 0;
                        rSum = zeros(size(flowNum));
                        
                        xShift = 0;
                        yShift = 0;
                        matState = getState(app,flow_rate,t,goalLocation, xShift, yShift);
                        %pyrun("featureExpectations = np.zeros(int(num_States))",num_States = n_states);
                        % reducedReward = rewardSpace - 0.01;
                        finished = false;
                        tStep = 0;
                        correctOutlet = false;
                        r_h = [];
                        r_hPlot=[];
                        for(i = 1:playingLoops)

                         %   xShift = ((i/playingLoops)-0.5)*2; %Make the values tried the same each time
                         %   yShift = ((i/playingLoops)-0.5)*2;
                         xShift = 0;
                         yShift = 0;

                            for(flowIndex = 1:length(flowNum))
                                flow_rate = flowNum(flowIndex); %Try each flow one at a time
                                finished = false;
                                resetSimForMLTraining(app,flow_rate,startZone,n_particles);
                                while(app.timePassed <= app.timeLimit && ~finished)
                                    tStep = tStep+1;
                                    [matState,rewardSum,matAction] = stepGame(app,n_actions,[],[],flow_rate,tStep,i==1,goalLocation,true, xShift, yShift);
        
                                    
                                    % if(any(app.particleArrayLocation(:,2)>0)||any(app.particleArrayLocation(:,2)<-0.008)) % set new end of thingy at just once the first particle gets past the first bifurcation on the correct side
                                    %     finished = true;
                                    % end
                                    if(sum(app.haltParticlesInEndZone)/app.numParticles == 1)
                                        finished = true;
                                    end
                                    % partsInEndZone = app.particleFunctions.isParticleInEndZone(app.polygon.currentEndZone,app.particleArrayLocation(1,:));%This should be 1 or 0
                                    % if(any(partsInEndZone))
                                    %     correctOutlet = true;
                                    %     finished = true;
                                    % end
                                    % if(app.particleArrayLocation(1,1)<-0.008)
                                    %     finished = true;
                                    % end
        
                                    distanceCount = distanceCount + 1;
        
                                    % r_h(end+1) = matReward;
                                    % delete(r_hPlot);
                                    % delete(lhDrawn);
                                    % delete(accDrawn);
                                    % delete(rewDrawn);
                                    % r_hPlot=plot(r_h);
                                    % pause(0.1);
                                end
                                rSum(flowIndex) = rSum(flowIndex)+rewardSum;
                                if(correctOutlet)
                                    goalCount = goalCount + 1;
                                else
                                    goalCount = goalCount - 1;
                                end
        
                                % if(rewardSum > bestPerformance)% || resetCount > length(summed_reward_histories)^0.7)
                                %     bestPerformance = rewardSum;
                                %     %resetCount = 0;
                                %     %pyrun("irl.model = rlTraining.model");                            
                                %     %
                                %     sss = pyrun("res = irl.model.save('E:\SwarmSimulation\BestModel')","res");
                                % %else
                                %     %resetCount = resetCount + 1;
                                %     %pyrun("rlTraining.model = irl.model");
                                % end
                            end
                        end
                        summed_reward_histories(traceStepCount,totalLoopIndex,:) = rSum./(playingLoops); %AVERAGE number of particles that reached goal FOR EACH FLOW%% over all flows!!()divide by number of flow rates to average across all.
                        
                        
                        delete(r_hPlot);
                        delete(lhDrawn);
                        delete(lhvDrawn);
                        delete(accDrawn);
                        delete(rewDrawn);
                        rewDrawn = plot(squeeze(summed_reward_histories(traceStepCount,:,:)));
                        hold on
                        title("Percentage of particles reaching the goal each episode during playing")
                        %legend("Percentage of particles reaching the goal");
                        xlabel("Play Iterations");
                        ylabel("Percentage of particles reaching the goal");
                        % if(length(summed_reward_histories)>1)
                        %     xlim([1,size(summed_reward_histories,2)]);
                        % end
                        legend(string(flowNum));
                        pause(0.9);
                        %hyprDist = pyrun("newHyperDistance = irl.UpdatePolicyList(FirstLayerWeights,PlayFE,stepI)","newHyperDistance", FirstLayerWeights = FirstLayerW, PlayFE = FE, stepI = stepIndex)
                        %hDistToPrint(end+1) = hyprDist;
                        %stepIndex = stepIndex+1;
                        % if(hyprDist <= e_psilon) %#hyperdistance is the 'error' if you will, so above we are positioning the new data in order of error? I think?
                        %    break
                        % else
                        %     %Reset NN (this should allow for better randomness?
                        %     pyrun("irl.model = irl.ConstructNetwork(irl.n_states, irl.n_actions, hiddenLayers)",hiddenLayers=h_Layers);
                        % end

                        % if(app.PercentageinGoalEditField.Value > 60 && startZone < 5)                            
                        %     startZone = startZone+1;
                        % else
                        %     if(app.PercentageinGoalEditField.Value < 2 && startZone > 1)
                        %         startZone = startZone - 1;
                        %     end
                        % end

                        resetSimForMLTraining(app,flow_rate,startZone,n_particles);
                        %Get a new policy to train
                        % currentExpertPolicy = loadNewPolicy(app,n_states,Gamma,goalLocation);

                        %'Train'
                        "Training Commencing"
                        for(trainingIndex = 1:trainingLoops)
                            t = 0;
                            % observe = 1;
                            %epsilon = 1-((trainingIndex/trainingLoops)*0.4); %reduce epsilon
                            batchSize = 10;
                            ePochs = 1;
                            % buffer = 20;
                            % %trainFrames = 10000;
                            % matState = getState(app,flow_rate,1,goalLocation);
                            % replayThingy = [];
                            % finished = false;
                            % % loss_history = [];
                            % % acc_history = [];
                            % episode_reward = 0;


                            % xTrain = [];
                            % xTest = [];
                            % yTrain = [];
                            % yTest = [];
                            % %randomIndicies = rand(size(stateList,1),size(stateList,2));
                            % randomIndicies = rand(size(stateList,1),1); %Just the first row as we are combining all particles in each trace into one sample.
                            % randomIndicies = randomIndicies>=trainTestSplit;
                            % for(traceStepCount = 1:size(randomIndicies,1))
                            %     %for(particleCount = 1:size(randomIndicies,2))
                            % 
                            %     % aaa = stateList(traceStepCount,:,:)
                            %     % asfb = size(stateList,2)
                            % 
                            %     %fluid_velocity = squeeze(flowList(traceStepCount));
                            %     particleSTD = var(squeeze(stateList(traceStepCount,:,:)),1);
                            %     particleAvg = sum(squeeze(stateList(traceStepCount,:,:)),1)./ size(stateList,2);
                            %     particleCoV = cov(squeeze(stateList(traceStepCount,:,1)),squeeze(stateList(traceStepCount,:,2)));
                            %     particleCoV = particleCoV(1,2);
                            %         %if(randomIndicies(traceStepCount,particleCount))
                            %         % if(randomIndicies(traceStepCount))
                            %         %     %xTrain(end+1,:) = stateList(traceStepCount,particleCount,:);
                            %         %     xTrain(end+1,:) = [particleAvg,particleCoV,particleSTD,flow_rate(1)];
                            %         %     yTrain(end+1,:) = actionList(traceStepCount,:);
                            %         % else
                            %         %     %xTest(end+1,:) = stateList(traceStepCount,particleCount,:);
                            %         %     xTest(end+1,:) = [particleAvg,particleCoV,particleSTD,flow_rate(1)];
                            %         %     yTest(end+1,:) = actionList(traceStepCount,:);
                            %         % end
                            %     % end
                            % end
                            % avgTrain = sum(yTrain,1)./size(yTrain,1)
                            % avgTest = sum(yTest,1)./size(yTest,1)
                            
                            hist = pyrun("uu = model.model.fit(np.array(state),np.array(target),batch_size=int(bS),epochs=int(ep), validation_data=(np.array(xT), np.array(yT)) ,verbose=0)","uu",state = stateList, target = actionList, bS = batchSize, ep = ePochs, xT = val_stateList, yT = val_actionList);

                            lh = double(hist.history{'loss'});
                            ah = double(hist.history{'accuracy'});
                            lhv = double(hist.history{'val_loss'});
                            ahv = double(hist.history{'val_accuracy'});
                            currentLineEnd = (totalLoopIndex-1) .* ePochs + 1;
                            loss_history(traceStepCount,currentLineEnd:currentLineEnd+ePochs-1) = lh;
                            loss_history_val(traceStepCount,currentLineEnd:currentLineEnd+ePochs-1) = lhv;
                            % loss_history(traceStepCount,end+1:end+ePochs) = lh; %%%%Can't use end - it puts it at the end of previous size, makes it 3 times too big.
                            % acc_history(traceStepCount,end+1:end+ePochs) = ah;
                            % loss_history_val(traceStepCount,end+1:end+ePochs) = lhv;
                            % acc_history_val(traceStepCount,end+1:end+ePochs) = ahv;
                            %loss_history(end+1) = double(hist.history{'loss'});
                            %acc_history(end+1) = double(hist.history{'accuracy'});

                            % epsilon
                            % figure
                            % plot(rewards_history);
                            % % legend(["reward"])
                            % %figure
                            
                            %Training_Progress = floor(trainingIndex/trainingLoops .*100)+"%"
                            resetSimForMLTraining(app,flow_rate,startZone,n_particles);
                        end

                        %print the weights for me!
                        pyrun("for layer in model.model.layers: print(layer.get_config(), layer.get_weights())");

                        delete(lhDrawn);
                        delete(accDrawn);
                        delete(lhvDrawn);
                        delete(accvDrawn);
                        delete(rewDrawn);
                        delete(r_hPlot);
                        lhDrawn = plot(squeeze(loss_history(traceStepCount,:,:)));
                        hold on
                        % accDrawn = plot(acc_history);
                        % hold on
                        lhvDrawn = plot(squeeze(loss_history_val(traceStepCount,:,:)));
                        hold on
                        % accvDrawn = plot(acc_history_val);
                        % hold on
                        % legend(["loss","accuracy","val-loss","val-accuracy"])
                        legend(["loss","val-loss"])
                        title("loss for training and validation while training")
                        xlabel("Train Iterations");
                        ylabel("");
                        % if(length(loss_history)>1)
                        %     xlim([1,size(loss_history,2)]);
                        % end
                        pause(0.1);

                    % end
                    % save("TrainedDataResults_all.mat","summed_reward_histories","loss_history","loss_history_val");
                    % if(traceStepCount==1)
                    %     save("TrainedDataResults_15.mat","summed_reward_histories","loss_history","loss_history_val");
                    % else 
                    %     if(traceStepCount==2)
                    %         save("TrainedDataResults_25.mat","summed_reward_histories","loss_history","loss_history_val");
                    %     else
                    %         save("TrainedDataResults_35.mat","summed_reward_histories","loss_history","loss_history_val");
                    %     end
                    % end
                 %   delete(lhDrawn);
                 %   delete(lhvDrawn);
                 %   delete(rewDrawn);

                    
                    %resetSimForMLTraining(app);
              %      app.fileID = fopen("MachineLearningTraining04.txt",'a');%Just appending, don't need to read anything.
              %      filedescriptionsdg = "19-05-23 01, n_states = " + num2str(n_states) + ", n_actions = " + num2str(n_actions) + ", h_Layers = " + num2str(h_Layers) + ", e_psilon = " + num2str(e_psilon) + " l_rate = " + num2str(l_rate) + " Gamma = " + num2str(Gamma) + " flow_rate = " + num2str(flow_rate) + " totalSteps = " + num2str(totalSteps) + " trainingLoops = " + num2str(trainingLoops);
        
              %      fprintf(app.fileID, filedescriptionsdg +"\r\n");
              %      fprintf(app.fileID, num2str(hDistToPrint) +"\r\n\r\n");
              %      fclose(app.fileID);
                    % figure
                    % plot(hDistToPrint);
                   end   
                end               
            end
            try
                save("TrainedDataResults_HiddenLayer.mat","summed_reward_histories","loss_history","loss_history_val");
            catch
                "Oops"
            end
            "Finished - Training Complete"
            
        end

        function moddedDataset = translateRotateTrainingData(app, rot, xtr, ytr, dataset)
            moddedDataset = dataset;
            %TODO rotate.
            moddedDataset(:,4) = moddedDataset(:,4) + xtr; %4&5 are avg x and y
            moddedDataset(:,5) = moddedDataset(:,5) + ytr;
            moddedDataset(:,7) = squeeze(ones(size(moddedDataset(:,1))) .* xtr);
            moddedDataset(:,8) = squeeze(ones(size(moddedDataset(:,1))) .* ytr);
          %  moddedDataset(4,:,:) = moddedDataset(4,:,:) + xtr; %4&5 are avg x and y
          %  moddedDataset(5,:,:) = moddedDataset(5,:,:) + ytr;
          %  moddedDataset(7,:,:) = squeeze(ones(size(moddedDataset(1,:,:))) .* xtr);
          %  moddedDataset(8,:,:) = squeeze(ones(size(moddedDataset(1,:,:))) .* ytr);
        end

        function [rewardXY] = loadNewRewardFromPolicy(app,minx,maxx,miny,maxy,binsx,binsy)
            %Make the state space
            rewardXY = zeros(binsx,binsy);

            if(app.DataStoreLocation == "")
                "Choose a Folder to read"
                app.DataStoreLocation = uigetdir
            end
            if(length(app.dataFileNameList) < 1)
                "Reading FileNames"
                dOuter = dir(app.DataStoreLocation);
                for dirCount = 1:length(dOuter)
                    innerFolderName = dOuter(dirCount).name;
                    if(~(strcmp(innerFolderName,'.') || strcmp(innerFolderName,'..')))
                        fileList = dir(strcat(dOuter(dirCount).folder,'\',dOuter(dirCount).name));
                        for fileCount = 1:length(fileList)
                            if(~(strcmp(fileList(fileCount).name,'.') || strcmp(fileList(fileCount).name,'..')) && length(fileList(fileCount).name) >= 1)
                                filePathToAdd = strcat(fileList(fileCount).folder,'\',fileList(fileCount).name);
                                app.dataFileNameList{end+1} = filePathToAdd;
                            end
                        end
                    end
                end
                "Done"
            end
            expertStates = [];
            expertActions = [];

            "Reading the expert file"
            %chosenFile = string(app.dataFileNameList(randi(length(app.dataFileNameList)))); %Pick one file at random (These should be ordered maybe?)
            %for(i = 1:length(app.dataFileNameList))
            for(i = 1:5)
                chosenFile = string(app.dataFileNameList(i));
                tl = 0;
                app.TimeRemainingsEditField.Value = tl;
                %flowRgfg = flowRatje(length(flowRatje));
                %flowRate = str2double(flowRate)%./6;
                fileToUse = fopen(chosenFile);
                tline = fgetl(fileToUse);
                %Similar to "play" loop above
                FE = 0;
                %FE = [];
                distanceCount = 0;
                finished = false;
                newExpertFE = [];
                while(ischar(tline) && ~finished)
                    datas = split(tline,',');
                    time = datas(1);
                    magForce = datas(2);
                    goalPercentage = datas(3);
                    velocities = datas(4);
                    positions = datas(5);
                    goalPositions = datas(6);
                    
                    allMag = strip(magForce,'[');
                    allMag = strip(allMag,']');
                    allMag = split(allMag,' ');
                    magx = str2double(allMag(1));
                    magy = str2double(allMag(2));
    
                    positions = strip(positions,'[');
                    positions = strip(positions,']');
                    allPositions = split(positions,';');
                    positionArray = [];
                    for(lineIndex = 1:length(allPositions))
                        yPos = split(allPositions(lineIndex)," ");
                        xPos = str2double(yPos(1));
                        yPos = str2double(yPos(2));
                        
                        binx = floor(((xPos - minx) / (maxx - minx))*binsx);
                        biny = floor(((yPos - miny) / (maxy - miny))*binsy);
                        if(binx<=binsx&&biny<=binsy&&binx>0&&biny>0)                            
                            rewardXY(binx,biny) = rewardXY(binx,biny) + 1;
                        end
                    end
    
                    distanceCount = distanceCount + 1;
                    tline = fgetl(fileToUse);
                end
                "File Read"
            end
        end

        function [expertStates,expertActions,flowRates] = loadStateActionPairsFromPolicy(app)% n_States, goalPoint, minx, miny, maxx, maxy)
            if(app.DataStoreLocation == "")
                "Choose a Folder to read"
                app.DataStoreLocation = uigetdir
            end
            if(length(app.dataFileNameList) < 1)
                "Reading FileNames"
                dOuter = dir(app.DataStoreLocation);
                for dirCount = 1:length(dOuter)
                    innerFolderName = dOuter(dirCount).name;
                    if(~(strcmp(innerFolderName,'.') || strcmp(innerFolderName,'..')))
                        fileList = dir(strcat(dOuter(dirCount).folder,'\',dOuter(dirCount).name));
                        for fileCount = 1:length(fileList)
                            if(~(strcmp(fileList(fileCount).name,'.') || strcmp(fileList(fileCount).name,'..')) && length(fileList(fileCount).name) >= 1)
                                filePathToAdd = strcat(fileList(fileCount).folder,'\',fileList(fileCount).name);
                                app.dataFileNameList{end+1} = filePathToAdd;
                            end
                        end
                    end
                end
                "Done"
            end
            expertStates = [];
            expertActions = [];
            flowRates = [];

            "Reading the expert file"
            chosenFile = string(app.dataFileNameList(randi(length(app.dataFileNameList)))); %Pick one file at random (These should be ordered maybe?)
            %Open the file to read
            flowRate = (split(chosenFile,'\'));
            flowag = flowRate(end-1);
            if(strlength(flowag)>1)
                flowRateNormalised = sscanf(flowag,'%*[^0123456789]%d');
            else
                flowRateNormalised = str2double(flowag);
            end
            if(isnan(flowRateNormalised))
                flowRateNormalised = realmin.*6;
                "There was some error with reading the flow rate - this data is bad..."
            end
            flowRates(end+1) = flowRateNormalised;
            % tl = 0;
            % switch(flowRateNormalised)
            %     case(1)
            %         tl=15;
            %     case(2)
            %         tl=15;
            %     case(3)
            %         tl=12;
            %     case(4)
            %         tl=12;
            %     case(5)
            %         tl=10;
            %     case(6)
            %         tl=10;
            % end
            % flowRateNormalised = flowRateNormalised./6;
            % app.TimeRemainingsEditField.Value = tl;
            %flowRgfg = flowRatje(length(flowRatje));
            %flowRate = str2double(flowRate)%./6;
            fileToUse = fopen(chosenFile);
            tline = fgetl(fileToUse);
            %Similar to "play" loop above
            %FE = 0;
            %FE = [];
            distanceCount = 0;
            finished = false;
            %newExpertFE = [];
            stateList = [];
            actionList = [];
            while(ischar(tline) && ~finished) %(app.timePassed <= app.timeLimit)
                                
                datas = split(tline,',');
                time = datas(1);
                magForce = datas(2);
                goalPercentage = datas(3);
                velocities = datas(4);
                positions = datas(5);
                goalPositions = datas(6);
                
                allMag = strip(magForce,'[');
                allMag = strip(allMag,']');
                allMag = split(allMag,' ');
                magx = str2double(allMag(1));
                magy = str2double(allMag(2));

                % velocities = strip(velocities,'[');
                % velocities = strip(velocities,']');
                % allVelocities = split(velocities,';');
                 positions = strip(positions,'[');
                 positions = strip(positions,']');
                % goalPositions = strip(goalPositions,'[');
                % goalPositions = strip(goalPositions,']');
                % allGoalPositions = split(goalPositions,';');
                 allPositions = split(positions,';');
                % %matState = [0 0 0 0];
                % sumAdded = 0;
                % xSum = 0;
                % ySum = 0;
                % distancex = 0;
                % distancey = 0;
                positionArray = [];
                 for(lineIndex = 1:length(allPositions))
                     yPos = split(allPositions(lineIndex)," ");
                     xPos = str2double(yPos(1));
                     yPos = str2double(yPos(2));
                     positionArray(end+1,:) = [xPos,yPos].*100;
                %     % if(yPos>0) % set new end of thingy at just once the first particle gets past the first bifurcation on the correct side
                %     %     finished = true;
                %     %     %break;
                %     % end
                    % if(yPos>0||yPos<-0.008) % set new end of thingy at just once the first particle gets past the first bifurcation on the correct side
                    %      finished = true;
                    %  end
                %     % if(all(app.haltParticlesInEndZone))
                %     %     finished = true;
                %     % end
                %     xSum = xSum - xPos;
                %     ySum = ySum - yPos; %TODO!!! REEMEMBER!!! This can't be negative if we're using anything above 0
                % 
                %     distancex = distancex + sqrt((xPos-goalPoint(1)).^2);% + 
                %     distancey = distancey + sqrt((yPos-goalPoint(2)).^2);
                % 
                 end
                % for(lineIndex = 1:length(allPositions))
                %     if(~contains(allGoalPositions(lineIndex),'1'))
                %      xAndYVelocities = split(allVelocities(lineIndex),' ');
                %      xAndYPositions = split(allPositions(lineIndex),' ');
                %      matState = matState + [str2double(xAndYPositions(1)), str2double(xAndYPositions(2)) ,str2double(xAndYVelocities(1)), str2double(xAndYVelocities(2))];
                %      sumAdded = sumAdded + 1;
                %     end
                % end
                % matState = matState ./ sumAdded; %Get the average...
                %matState = [distancex, distancey, flowRateNormalised];
                expertActions(end+1,:) = [magx,magy];
                expertStates(end+1,:,:) = positionArray;

                distanceCount = distanceCount + 1;
                tline = fgetl(fileToUse);
            end
            avgForce = sum(expertActions,1)./size(expertActions,1)
             % if(length(FE) ~= n_States) %This is recursive - make sure to not get stuck here...
             %    newExpertFE = loadNewPolicy(app,n_States,Gamma,goalPoint);
             % end
            "File Read"
        end

        function [expertStates,expertActions,valExpertStates, valExpertActions,flowRates] = loadStateActionValPairsFromPolicy(app, numberOfValidationTraces,CountNumber)% n_States, goalPoint, minx, miny, maxx, maxy)
            

            IMPORTEDexpertStates = [];
            IMPORTEDexpertActions = [];
            IMPORTEDFILEDELIMITERS = [];
            expertStates = [];
            expertActions = [];
            valExpertStates = [];
            valExpertActions = [];
            flowRates = [];
            %Check if we need to load from the mat file or not
            if isfile("storedTrainingVariables.mat")
                load("storedTrainingVariables.mat","IMPORTEDexpertStates", "IMPORTEDexpertActions", "IMPORTEDFILEDELIMITERS", "flowRates");
            else
                impExpertStates=[];
                impExpertActions=[];
                impFileDelimiters=[];
               % impFlowRates=[];
                if(isfile("storedTrainingVariables12-15.mat"))
                    "reading binary files"
                    if(CountNumber==1)
                        fileNames = ["storedTrainingVariables12-15.mat","storedTrainingVariables34-15.mat","storedTrainingVariables56-15.mat"];
                    else
                        if(CountNumber==2)
                            fileNames = ["storedTrainingVariables12-25.mat","storedTrainingVariables34-25.mat","storedTrainingVariables56-25.mat"];
                        else
                            fileNames = ["storedTrainingVariables12-35.mat","storedTrainingVariables34-35.mat","storedTrainingVariables56-35.mat"];
                        end
                    end
                    for(SomeCounter=1:length(fileNames))
                        load(fileNames(SomeCounter),"IMPORTEDexpertStates", "IMPORTEDexpertActions", "IMPORTEDFILEDELIMITERS");%, "flowRates");
                        impExpertStates = cat(1,impExpertStates,IMPORTEDexpertStates);
                        impExpertActions = cat(1,impExpertActions,IMPORTEDexpertActions);
                        impFileDelimiters = cat(2,impFileDelimiters,IMPORTEDFILEDELIMITERS);
                        %impFlowRates = cat(1,impFlowRates,flowRates);
                    end
                    IMPORTEDexpertStates = impExpertStates;
                    IMPORTEDexpertActions = impExpertActions;
                    IMPORTEDFILEDELIMITERS = impFileDelimiters;
                    clearvars impExpertStates impExpertActions impFileDelimiters;
                   % flowRates = impFlowRates;
                else
                    if(app.DataStoreLocation == "")
                        "Choose a Folder to read"
                        app.DataStoreLocation = uigetdir
                    end
                    if(length(app.dataFileNameList) < 1)
                        "Reading FileNames"
                        dOuter = dir(app.DataStoreLocation);
                        for dirCount = 1:length(dOuter)
                            innerFolderName = dOuter(dirCount).name;
                            if(~(strcmp(innerFolderName,'.') || strcmp(innerFolderName,'..')))
                                fileList = dir(strcat(dOuter(dirCount).folder,'\',dOuter(dirCount).name));
                                for fileCount = 1:length(fileList)
                                    if(~(strcmp(fileList(fileCount).name,'.') || strcmp(fileList(fileCount).name,'..')) && length(fileList(fileCount).name) >= 1)
                                        filePathToAdd = strcat(fileList(fileCount).folder,'\',fileList(fileCount).name);
                                        app.dataFileNameList{end+1} = filePathToAdd;
                                    end
                                end
                            end
                        end
                    "Done"
                    end
                end                
    
                "Reading the expert files"
                for(fileCount = 1:length(app.dataFileNameList))
                    chosenFile = string(app.dataFileNameList(fileCount));
                    %Open the file to read
                    flowRate = (split(chosenFile,'\'));
                    flowag = flowRate(end-1);
                    if(strlength(flowag)>1)
                        flowRateNormalised = sscanf(flowag,'%*[^0123456789]%d');
                    else
                        flowRateNormalised = str2double(flowag);
                    end
                    if(isnan(flowRateNormalised))
                        flowRateNormalised = realmin.*6;
                        "There was some error with reading the flow rate - this data is bad..."
                    end
                    fileToUse = fopen(chosenFile);
                    tline = fgetl(fileToUse);
                    finished = false;
                    lineCount=0;
                    LastPercentage = 0;
                    StatesToAdd = [];
                    ActionsToAdd = [];
                    while(ischar(tline) && ~finished) %(app.timePassed <= app.timeLimit)
                        lineCount = lineCount + 1;
                        datas = split(tline,',');
                       % time = datas(1);
                        magForce = datas(2);
                       % goalPercentage = datas(3);
                       LastPercentage = str2double(datas(3));
                       % velocities = datas(4);
                        positions = datas(5);
                       % goalPositions = datas(6);
                        
                        allMag = strip(magForce,'[');
                        allMag = strip(allMag,']');
                        allMag = split(allMag,' ');
                        magx = str2double(allMag(1));
                        magy = str2double(allMag(2));
        
                        positions = strip(positions,'[');
                        positions = strip(positions,']');
                        allPositions = split(positions,';');
                        positionArray = [];
                        for(lineIndex = 1:length(allPositions))
                            yPos = split(allPositions(lineIndex)," ");
                            xPos = str2double(yPos(1));
                            yPos = str2double(yPos(2));
                            positionArray(end+1,:) = [xPos.*100, yPos.*100,flowRateNormalised];
                        end
                        ActionsToAdd(end+1,:) = [magx,magy];
                        % if(lineCount==1)                            
                        %     %IMPORTEDexpertActions(end,:) = [-100,-100];
                        %     %IMPORTEDexpertActions(end,:) = [-100,-100];
                        % end
                        StatesToAdd(end+1,:,:) = positionArray;
                        tline = fgetl(fileToUse);
                    end
                    if(LastPercentage>0.35)
                        adsghaa = size(StatesToAdd,1)
                        IMPORTEDexpertStates(end+1:end+size(StatesToAdd,1),:,:) = StatesToAdd;
                        IMPORTEDexpertActions(end+1:end+size(ActionsToAdd,1),:) = ActionsToAdd;
                        IMPORTEDFILEDELIMITERS(end+1) = lineCount;
                    end
                    fclose(fileToUse);
                    "File" + fileCount + "/" + length(app.dataFileNameList) + " Read"
                end
                if(~isfile("storedTrainingVariables12-25.mat"))
                    save("storedTrainingVariables.mat","IMPORTEDexpertStates", "IMPORTEDexpertActions", "IMPORTEDFILEDELIMITERS", "flowRates");
                end
            end

            if(numberOfValidationTraces<1)
                lengthValidation = floor(numberOfValidationTraces .* length(IMPORTEDFILEDELIMITERS));
            else
                lengthValidation = numberOfValidationTraces;
            end

            validationSet = randi(length(IMPORTEDFILEDELIMITERS),lengthValidation,1);
            fileStartCursor = 1;
            for(fileCount = 1:length(IMPORTEDFILEDELIMITERS))
                if(any(validationSet(:) == fileCount))                    
                    valExpertActions(end+1:end+IMPORTEDFILEDELIMITERS(fileCount),:) = squeeze(IMPORTEDexpertActions(fileStartCursor:(fileStartCursor + IMPORTEDFILEDELIMITERS(fileCount)-1),:));
                    valExpertStates(end+1:end+IMPORTEDFILEDELIMITERS(fileCount),:,:) = squeeze(IMPORTEDexpertStates(fileStartCursor:(fileStartCursor + IMPORTEDFILEDELIMITERS(fileCount)-1),:,:));
                else                    
                    aaa = squeeze(IMPORTEDexpertActions(fileStartCursor:(fileStartCursor + IMPORTEDFILEDELIMITERS(fileCount)-1),:));
                    expertActions(end+1:end+IMPORTEDFILEDELIMITERS(fileCount),:) = squeeze(IMPORTEDexpertActions(fileStartCursor:(fileStartCursor + IMPORTEDFILEDELIMITERS(fileCount)-1),:));
                    expertStates(end+1:end+IMPORTEDFILEDELIMITERS(fileCount),:,:) = squeeze(IMPORTEDexpertStates(fileStartCursor:(fileStartCursor + IMPORTEDFILEDELIMITERS(fileCount)-1),:,:));
                end
                fileStartCursor = fileStartCursor+IMPORTEDFILEDELIMITERS;
            end
          %  valExpertStates = squeeze(expertStates(validationSet,:,:,:));
          %  valExpertActions = squeeze(expertActions(validationSet,:,:,:));

        end

        function sl = statePairsToCorrectState(app, stateList)
            xSTD = std(squeeze(stateList(:,:,1)),0,2);
            ySTD = std(squeeze(stateList(:,:,2)),0,2);
            xAVG = sum(squeeze(stateList(:,:,1)),2)./ size(stateList,2);
            yAVG = sum(squeeze(stateList(:,:,2)),2)./ size(stateList,2);
            covar = zeros(size(stateList,1),1);
            for(i = 1:size(stateList,1))
                particleCoV = cov(squeeze(stateList(i,:,1)),squeeze(stateList(i,:,2)));
                covar(i) = particleCoV(1,2);
            end
            flowRate = squeeze(stateList(:,1,3));%Just need it for one particle, lots of spare data here
            sl = [xSTD, ySTD, covar, xAVG, yAVG, flowRate];
        end

        function resetSimForMLTraining(app,flowRate,startZone,nParticles)
            startZone = 7;
            delete(app.particlePoints);
            delete(app.magLine);
            fclose(app.fileID);
            app.ScenarioEditField.Value = "Test " + app.testNumber;
            app.polygon = app.polygon.change(2,app.fd);
            app.rotation = 0;
            app.NumberofParticlesEditField.Value = nParticles;
            app.MagForceRestrictMAM2EditField.Value = 0;
            generateNewParticles = true;
            app.hapticFeedback = [0,0,0];
            app.fd = FlowData60();
            app.polygon = app.polygon.change(4,app.fd);
            app.FluidFlowmsEditField.Value = flowRate.*6;
            if(ndims(app.polygon.currentStartZone) > 2)
                app.polygon.currentStartZone = squeeze(app.polygon.currentStartZone(1,:,:));
            end
            app.goalIndex = 4;
            app.slowDown = 1;
            % % % % % % % minTimeToTravel = 15;
            minTimeToTravel = 2*startZone + 2;
            app.TimeRemainingsEditField.Value = minTimeToTravel;% .* 5;%2.5;TODO RESET ME
            app.timeLimit = app.TimeRemainingsEditField.Value;
            app.numParticles = app.NumberofParticlesEditField.Value;
            app.previousMagforce = 0;
            app.lastUpdate = clock;
            paddedTestNumber = sprintf( '%04d', app.testNumber);
            newFileName = "Test" + paddedTestNumber + "_" + app.lastUpdate(4) + "_" + app.lastUpdate(5) + "_results.csv";
            app.fileID = fopen(newFileName,'w');
            app.loopComplete = true;            
            app.particlePoints = plot(app.UIAxes,0,0);
            app.tMax = 1;
        
        
            app.mesh = delaunayTriangulation(app.polygon.currentFlowLocations);
            if(generateNewParticles)
                app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);
             end
            app.particleArrayVelocity = app.particleFunctions.calculateFlow(app.particleArrayLocation,app.polygon.currentFlowValues,app.mesh);% zeros(app.numParticles, 2);
            
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayBaseVelocityStartTime = clock;
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            app.bouncedLastLoop = zeros(app.numParticles,1);
            
            hold (app.UIAxes, 'on');
            delete(app.polyLine);
            delete(app.endLine);
            delete(app.wrongEndLine); 
            
            rotateMatrix = [cosd(app.rotation), sind(app.rotation); -sind(app.rotation), cos(app.rotation)];
            rotatedOutline = (rotateMatrix * app.polygon.currentPoly')';
            app.polyLine = plot(app.UIAxes, rotatedOutline(:,1), rotatedOutline(:,2), 'Color','b');   


            %set(app.UIAxes,'padded')
            app.UIAxes.XLim = [min(app.polygon.currentPoly(:,1)) + (min(app.polygon.currentPoly(:,1))/20), max(app.polygon.currentPoly(:,1)) + (max(app.polygon.currentPoly(:,1))/20)];
            app.UIAxes.YLim = [min(app.polygon.currentPoly(:,2)) + (min(app.polygon.currentPoly(:,2))/20), max(app.polygon.currentPoly(:,2)) + (max(app.polygon.currentPoly(:,2))/20)];


            for(lineCount = 1:size(app.polygon.currentEndZone,1))
                rotatedEndZone = (rotateMatrix * squeeze(app.polygon.currentEndZone(lineCount,:,:))')';
                app.wrongEndLine(lineCount) = plot(app.UIAxes, rotatedEndZone(:,1), rotatedEndZone(:,2), 'Color','r');
                if(lineCount == app.goalIndex)
                    app.endLine = plot(app.UIAxes, rotatedEndZone(:,1), rotatedEndZone(:,2), 'Color','g');
                end
            end 
            app.previousMagforce = 0;
            app.X1MAGauge.Value = 0;
            app.Y1MAGauge.Value = 0;
           % app.fd = FlowData();
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
            app.currentlyDoingWorkSemaphore = false;
            app.timePassed = 0;
            app.timestep = 0;
            app.timeLag = 0;
            app.mousePosition = [0 0];
            app.magLine = plot(app.UIAxes,0,0); 
            newStartZone = app.polygon.currentStartZone;
            allStartZones = [];
            allStartZones(1,:,:) = [0.0085 0.00124; 0.0076 0.00112; 0.008 0.001; 0.0088 0.001; 0.0085 0.00124];
            allStartZones(2,:,:) = [0.0045 0.002; 0.0046 0.002; 0.0046 0.0015; 0.0045 0.0015; 0.0045 0.002];
            allStartZones(3,:,:) = [0.0035 0.0038; 0.0029 0.0030; 0.0033 0.0030; 0.0038 0.0035; 0.0035 0.0038];
            allStartZones(4,:,:) = [0.0045 0.0005; 0.0046 0.0005; 0.0046 0; 0.0045 0; 0.0045 0.0005];
            allStartZones(5,:,:) = [-0.0018 0.0021; -0.0017 0.0012; -0.0015 0.0015; -0.0015 0.0025;-0.0018 0.0021];
            allStartZones(6,:,:) = [-0.0045 -0.003; -0.0036 -0.0035; -0.0031 -0.0033; -0.0044 -0.0032;-0.0045 -0.003];
            allStartZones(7,:,:) = [app.polygon.currentStartZone];
            %allStartZones(6) = [];
            newStartZone = squeeze(allStartZones(startZone,:,:));
            %newStartZone = squeeze(allStartZones(1,:,:));
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(newStartZone, app.numParticles);
        end

        function [state,reward,action] = stepGame(app, n_actions, expertStates, expertActions, flowRate, t, draw, goalLocation, playing, xShift, yShift)
            if(length(expertActions) > 1)  
                action = expertActions;
            else
                    action = double(pyrun("pred = model.model.predict(np.array([state]),verbose=0, batch_size=1)","pred", state=getState(app,flowRate,t,goalLocation, xShift, yShift)));
                    % prediction = double(pyrun("pred = irl.model.predict(np.array([state]),verbose=0, batch_size=1)","pred", state=getState(app,flowRate,t,goalLocation)));
                    % if(playing)
                    %     [mm,action] = max(prediction);
                    % else
                    %     probcs = cumsum(prediction./sum(prediction));
                    %     randindex = rand();
                    %     indicies = probcs<randindex; %Then use first false
                    %     for(action=1:length(probcs))%Streamline this
                    %         %action=i;
                    %         if(~indicies(action))
                    %             break;
                    %         end
                    %     end
                    % end
            end
            
            % %action 1 = 12 & a little bit o clock - "0"x, +y
            % %action n/2 = 6 o clock - 0x, -y
            % %action n = 12 o clock - 0x, +y
            % quarter = n_actions/4;
            % half = n_actions/2;
            % yval = (quarter - action)./quarter;
            % if(yval < -1)
            %     yval  = (action - quarter*3)./quarter;
            % end
            % xval = (half - action)./half;
            % if(xval>1)
            %     xval = 2-xval;
            % else
            %     if(xval<-1)
            %         xval = -2 -xval;
            %     end
            % end
            % switch(action)
            %     case(1)
            %         app.X1MAGauge.Value = 0;
            %         app.Y1MAGauge.Value = 1;
            %     case(2)
            %         app.X1MAGauge.Value = 1;
            %         app.Y1MAGauge.Value = 0;
            %     case(3)
            %         app.X1MAGauge.Value = 0;
            %         app.Y1MAGauge.Value = -1;
            %     case(4)
            %         app.X1MAGauge.Value = -1;
            %         app.Y1MAGauge.Value = 0;
            % end

            app.X1MAGauge.Value = action(1).*2.5;
            app.Y1MAGauge.Value = action(2).*2.5;

            timerCallback(app); %Do the action

            if(length(expertStates) > 1)
                %Force the state to be like the data
                app.particleArrayLocation = expertStates;
            end
            if(draw)
                drawTimerCallback(app); %Draw it (or don't)
                drawnow limitrate
                %pause(0.001);
            end
            state = getState(app,flowRate,t,goalLocation, xShift, yShift);
            reward = app.particleFunctions.isParticleInEndZone(app.polygon.currentEndZone,app.particleArrayLocation(:,:));%This should be 1 or 0
            %asgasgf = abs(state(1))+abs(state(2))
            reward = sum(reward(:,app.goalIndex))./size(app.particleArrayLocation,1).*100;% + 0.0005/(abs(state(1))+abs(state(2)));
            % reward = reward(app.goalIndex) .* (350-t);
            % reward = 0;
        end

        function matState = getState(app,FlowRate,t,goalPoint, xOffset, yOffset)
            % distancex = sqrt((app.particleArrayLocation(:,1)-goalPoint(1)).^2);
            % distancey = sqrt((app.particleArrayLocation(:,2)-goalPoint(2)).^2);
            % matState = [app.particleArrayLocation(1,1).*100,app.particleArrayLocation(1,2).*100];
            %matState = [distancex(1), distancey(1), app.particleArrayLocation(1,1).*100,app.particleArrayLocation(1,2).*100,t./100];
            xLoc = (app.particleArrayLocation(:,1) + xOffset).*100;%Can't forget to multiply by 100 to make it roughly 0-1
            yLoc = (app.particleArrayLocation(:,2) + yOffset).*100;
            covar = cov(xLoc,yLoc);
            covar = covar(1,2);
           % matState = [std(xLoc), std(yLoc),covar, sum(xLoc)./size(xLoc,1), sum(yLoc)./size(yLoc,1), FlowRate];
            matState = [std(xLoc), std(yLoc),covar, sum(xLoc)./size(xLoc,1), sum(yLoc)./size(yLoc,1), FlowRate, xOffset, yOffset];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if(app.ButtonGroup.SelectedObject.Text == "Controller")
               CloseHaptic();
            end
            try
                f.close(app.fileID);
            catch
                "No file to close";
            end
            close all;
            delete(app)
            clear;
            disp("App Stopped")
        end

        % Callback function
        function XCoilsSwitchValueChanged(app, event)
            if(app.XCoilsSwitch.Value == '0')
                app.X1MAGauge.Value = 0;
                app.X2MAGauge.Value = 0;
            end
        end

        % Callback function
        function YCoilsSwitchValueChanged(app, event)
            if(app.YCoilsSwitch.Value == '0')
                app.Y1MAGauge.Value = 0;
                app.Y2MAGauge.Value = 0;
            end            
        end

        % Value changed function: ParticleDiametermEditField
        function ParticleDiametermEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: StaticFrictionCoefficientEditField
        function StaticFrictionCoefficientEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: MotionFrictionCoefficientEditField
        function MotionFrictionCoefficientEditFieldValueChanged(app, event)
            app.UIAxes.XLim(2)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: FluidViscocityEditField
        function FluidViscocityEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ParticleMasskgEditField
        function ParticleMasskgEditFieldValueChanged(app, event)
            app.particleFunctions = app.particleFunctions.ChangeMetaValue(1.25663706e-6, app.ParticleDiametermEditField.Value, app.ParticleMasskgEditField.Value, app.FluidViscocityEditField.Value, app.StaticFrictionCoefficientEditField.Value, app.MotionFrictionCoefficientEditField.Value, app.UIAxes.XLim(2));
        end

        % Value changed function: ScenarioDropDown
        function ScenarioDropDownValueChanged(app, event)

        end

        % Button pushed function: StartTrainingButton
        function StartTrainingOLDButtonValueChanged(app, event)
           
        end

        % Value changed function: FluidFlowmsEditField
        function FluidFlowmsEditFieldValueChanged(app, event)
            value = app.FluidFlowmsEditField.Value;
            %app.fd = FlowData();
            %app.fd.FlowValues = app.fd.FlowValues.* value;
        end

        % Value changed function: MagForceRestrictMAM2EditField
        function MagForceRestrictMAM2EditFieldValueChanged(app, event)
            app.X1MAGauge.Value = 0;
            app.Y1MAGauge.Value = 0;
        end

        % Callback function
        function JoyStickIDEditFieldValueChanged(app, event)
            value = app.JoyStickIDEditField.Value;
            if value > 0
                try
                    app.joyStick = vrjoystick(value);
                    app.VerticalJoyStickAxisEditField.Enable = true;
                    app.HorizontalJoyStickAxisEditField.Enable = true;
                catch exception
                    app.joyStick = 0;
                end                
            else
                app.joyStick = 0;
                app.VerticalJoyStickAxisEditField.Enable = false;
                app.HorizontalJoyStickAxisEditField.Enable = false;
            end
        end

        % Value changed function: NumberofParticlesEditField
        function NumberofParticlesEditFieldValueChanged(app, event)
            app.numParticles = app.NumberofParticlesEditField.Value;
            app.particleArrayLocation = app.particleFunctions.generateParticleLocations(app.polygon.currentStartZone, app.numParticles);          
            app.particleArrayVelocity = zeros(app.numParticles, 2);
            app.particleArrayForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousLocation = app.particleArrayLocation;
            app.particleArrayBaseVelocity = zeros(app.numParticles, 2);
            app.particleArrayPreviousDragForce = zeros(app.numParticles, 2);
            app.particleArrayPreviousAcceleration = zeros(app.numParticles, 2);
            app.haltParticlesInEndZone = zeros(app.numParticles,1);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            CloseHaptic();
            app.X1MAGauge.Value = 0;
            app.Y1MAGauge.Value = 0;
            if(selectedButton.Text == "Mouse")
                app.controlMethod = "Mouse";
            elseif(selectedButton.Text == "Controller")
                    app.controlMethod = "Controller";
                    delete(app.magLine);
                    app.mousePosition = [0 0];
                    InitHaptic();
            else
                app.controlMethod = "Keyboard";
                delete(app.magLine);
                app.mousePosition = [0 0];
            end
        end

        % Callback function
        function UIFigureButtonDown(app, event)
        import java.awt.*;
        import java.awt.event.*;
        %Create a Robot-object to do the key-pressing
        rob=Robot;
        %Commands for pressing keys:
        % If the text cursor isn't in the edit box allready, then it
        % needs to be placed there for ctrl+a to select the text.
        % Therefore, we make sure the cursor is in the edit box by
        % forcing a mouse button press:
        rob.mousePress(InputEvent.BUTTON1_MASK );
        rob.mouseRelease(InputEvent.BUTTON1_MASK );
        % CONTROL + A : 
        rob.keyPress(KeyEvent.VK_SPACE)
        rob.keyRelease(KeyEvent.VK_SPACE)
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            if(app.controlMethod == "Mouse")
                MouseXY = get(app.UIAxes, 'CurrentPoint');
                app.mousePosition = MouseXY(1,1:2);
                if(app.MagForceRestrictMAM2EditField.Value > 0 && app.MagForceRestrictMAM2EditField.Value <= 2.5)
                    app.mousePosition = app.mousePosition ./ norm(app.mousePosition) .* app.MagForceRestrictMAM2EditField.Value ./ 225;
                else                    
                    if(norm(app.mousePosition) > 0.01)
                        app.mousePosition = app.mousePosition ./ norm(app.mousePosition) .* 0.01;
                    end
                end
                app.X1MAGauge.Value = app.mousePosition(1) .* 250;
                app.Y1MAGauge.Value = app.mousePosition(2) .* 250;
            end
        end

        % Value changed function: PercentageinGoalEditField
        function PercentageinGoalEditFieldValueChanged(app, event)
            value = app.PercentageinGoalEditField.Value;
            app.timeLimit = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {753, 753};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {165, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ParticleDiametermEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleDiametermEditFieldLabel.Visible = 'off';
            app.ParticleDiametermEditFieldLabel.Text = 'Particle Diameter (m)';
            app.ParticleDiametermEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleDiametermEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleDiametermEditFieldValueChanged, true);
            app.ParticleDiametermEditField.Editable = 'off';
            app.ParticleDiametermEditField.Visible = 'off';
            app.ParticleDiametermEditField.Value = 5.6e-06;
            app.X1MAGaugeLabel = uilabel(app.LeftPanel);
            app.X1MAGaugeLabel.Text = 'X1 (MA)';
            app.X1MAGauge = uigauge(app.LeftPanel, 'linear');
            app.X1MAGauge.Limits = [-2.5 2.5];
            app.X1MAGauge.MinorTicks = [-2.5 -2 -1.5 -1 -0.5 0.5 1 1.5 2 2.5];
            app.Y1MAGaugeLabel = uilabel(app.LeftPanel);
            app.Y1MAGaugeLabel.Text = 'Y1 (MA)';
            app.Y1MAGauge = uigauge(app.LeftPanel, 'linear');
            app.Y1MAGauge.Limits = [-2.5 2.5];
            app.Y1MAGauge.MinorTicks = [-2.5 -2 -1.5 -1 -0.5 0.5 1 1.5 2 2.5];
            app.StaticFrictionCoefficientEditFieldLabel = uilabel(app.LeftPanel);
            app.StaticFrictionCoefficientEditFieldLabel.Visible = 'off';
            app.StaticFrictionCoefficientEditFieldLabel.Text = {'(Static) Friction Coefficient'; ''};
            app.StaticFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StaticFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @StaticFrictionCoefficientEditFieldValueChanged, true);
            app.StaticFrictionCoefficientEditField.Editable = 'off';
            app.StaticFrictionCoefficientEditField.Visible = 'off';
            app.StaticFrictionCoefficientEditField.Value = 0.000135;
            app.MotionFrictionCoefficientLabel = uilabel(app.LeftPanel);
            app.MotionFrictionCoefficientLabel.Visible = 'off';
            app.MotionFrictionCoefficientLabel.Text = {'(Motion) Friction Coefficient'; ''};
            app.MotionFrictionCoefficientEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MotionFrictionCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @MotionFrictionCoefficientEditFieldValueChanged, true);
            app.MotionFrictionCoefficientEditField.Editable = 'off';
            app.MotionFrictionCoefficientEditField.Visible = 'off';
            app.MotionFrictionCoefficientEditField.Value = 3e-05;
            app.FluidViscocityEditFieldLabel = uilabel(app.LeftPanel);
            app.FluidViscocityEditFieldLabel.Visible = 'off';
            app.FluidViscocityEditFieldLabel.Text = 'Fluid Viscocity';
            app.FluidViscocityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FluidViscocityEditField.ValueChangedFcn = createCallbackFcn(app, @FluidViscocityEditFieldValueChanged, true);
            app.FluidViscocityEditField.Editable = 'off';
            app.FluidViscocityEditField.Visible = 'off';
            app.FluidViscocityEditField.Value = 0.004;
            app.ParticleMasskgEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleMasskgEditFieldLabel.Visible = 'off';
            app.ParticleMasskgEditFieldLabel.Text = 'Particle Mass (kg)';
            app.ParticleMasskgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ParticleMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @ParticleMasskgEditFieldValueChanged, true);
            app.ParticleMasskgEditField.Editable = 'off';
            app.ParticleMasskgEditField.Visible = 'off';
            app.ParticleMasskgEditField.Value = 2.069e-13;
            app.ScenarioDropDownLabel = uilabel(app.LeftPanel);
            app.ScenarioDropDownLabel.Visible = 'off';
            app.ScenarioDropDownLabel.Text = {'Scenario'; ''};
            app.ScenarioDropDown = uidropdown(app.LeftPanel);
            app.ScenarioDropDown.Items = {'Training1', 'Training2', 'Test 1', 'Test 2', 'Test 3', 'Test 4', 'Test 5', 'Test 6', 'Test 7'};
            app.ScenarioDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenarioDropDownValueChanged, true);
            app.ScenarioDropDown.Visible = 'off';
            app.ScenarioDropDown.Tooltip = {'Select which scenario to use. The experiment leader will tell you which Scenario to use.'};
            app.ScenarioDropDown.Value = 'Training1';
            app.MagForceRestrictMAM2EditFieldLabel = uilabel(app.LeftPanel);
            app.MagForceRestrictMAM2EditFieldLabel.Text = 'MagForceRestrict (MA/M^2)';
            app.MagForceRestrictMAM2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.MagForceRestrictMAM2EditField.ValueChangedFcn = createCallbackFcn(app, @MagForceRestrictMAM2EditFieldValueChanged, true);
            app.MagForceRestrictMAM2EditField.Editable = 'off';
            app.FluidFlowmsEditFieldLabel = uilabel(app.LeftPanel);
            app.FluidFlowmsEditFieldLabel.Text = 'FluidFlow(m/s)';
            app.FluidFlowmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FluidFlowmsEditField.ValueChangedFcn = createCallbackFcn(app, @FluidFlowmsEditFieldValueChanged, true);
            app.FluidFlowmsEditField.Editable = 'off';
            app.NumberofParticlesEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofParticlesEditFieldLabel.Text = 'Number of Particles';
            app.NumberofParticlesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofParticlesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofParticlesEditFieldValueChanged, true);
            app.NumberofParticlesEditField.Editable = 'off';
            app.NumberofParticlesEditField.Value = 50;
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Visible = 'off';
            app.KeyboardButton = uiradiobutton(app.ButtonGroup);
            app.KeyboardButton.Text = 'Keyboard';
            app.MouseButton = uiradiobutton(app.ButtonGroup);
            app.MouseButton.Text = 'Mouse';
            app.ControllerButton = uiradiobutton(app.ButtonGroup);
            app.ControllerButton.Text = 'Controller';
            app.ControllerButton.Value = true;
            app.CentreMassViewCheckBox = uicheckbox(app.LeftPanel);
            app.CentreMassViewCheckBox.Enable = 'off';
            app.CentreMassViewCheckBox.Visible = 'off';
            app.CentreMassViewCheckBox.Text = 'Centre Mass View';
            app.ScenarioEditFieldLabel = uilabel(app.LeftPanel);
            app.ScenarioEditFieldLabel.Text = 'Scenario';
            app.ScenarioEditField = uieditfield(app.LeftPanel, 'text');
            app.ScenarioEditField.Editable = 'off';
            app.ScenarioEditField.Value = 'Training';
            app.StartTrainingButton = uibutton(app.LeftPanel, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingOLDButtonValueChanged, true);
            app.StartTrainingButton.Text = 'Start Training';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Workspace')
            xlabel(app.UIAxes_2, 'X (m)')
            ylabel(app.UIAxes_2, 'Y (m)')
            zlabel(app.UIAxes_2, 'Z (m)')
            app.UIAxes_2.XLim = [-0.0096 0.0096];
            app.UIAxes_2.YLim = [-0.0096 0.0096];
            app.UIAxes_2.ZLim = [-0.0096 0.0096];
            app.UIAxes_2.XTick = [-0.008 -0.006 -0.004 -0.002 0 0.002 0.004 0.006 0.008];
            app.UIAxes_2.Tag = 'particleWorskace';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Workspace')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z (m)')
            app.UIAxes.XLim = [-0.0096 0.0096];
            app.UIAxes.YLim = [-0.0096 0.0096];
            app.UIAxes.ZLim = [-0.0096 0.0096];
            app.UIAxes.XTick = [-0.008 -0.006 -0.004 -0.002 0 0.002 0.004 0.006 0.008];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes.Tag = 'particleWorskace';
            app.TimeRemainingsEditFieldLabel = uilabel(app.RightPanel);
            app.TimeRemainingsEditFieldLabel.Text = {'Time'; 'Remaining (s)'};
            app.TimeRemainingsEditField = uieditfield(app.RightPanel, 'numeric');
            app.TimeRemainingsEditField.Editable = 'off';
            app.TimeRemainingsEditField.Value = 1;
            app.TimeremainingsLabel = uilabel(app.RightPanel);
            app.TimeremainingsLabel.Text = {'Percentage'; 'in Goal'};
            app.PercentageinGoalEditField = uieditfield(app.RightPanel, 'numeric');
            app.PercentageinGoalEditField.ValueChangedFcn = createCallbackFcn(app, @PercentageinGoalEditFieldValueChanged, true);
            app.PercentageinGoalEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = particleSimulationTrainModeFFPlayFirstHomePC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end